classdef DH_COMPI_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        COMPILauncherPanel           matlab.ui.container.Panel
        SubjectcodeEditFieldLabel    matlab.ui.control.Label
        SubjectcodeEditField         matlab.ui.control.EditField
        TaskButtonGroup              matlab.ui.container.ButtonGroup
        IOIO                         matlab.ui.control.RadioButton
        WM                           matlab.ui.control.RadioButton
        MMN                          matlab.ui.control.RadioButton
        Resting                      matlab.ui.control.RadioButton
        SessionButtonGroup           matlab.ui.container.ButtonGroup
        Practice                     matlab.ui.control.RadioButton
        Experiment                   matlab.ui.control.RadioButton
        RunButtonGroup               matlab.ui.container.ButtonGroup
        run_1                        matlab.ui.control.RadioButton
        run_2                        matlab.ui.control.RadioButton
        EEGrecordingstartedCheckBox  matlab.ui.control.CheckBox
        StartButton                  matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        WMButtonGroup                matlab.ui.container.ButtonGroup
        Memory                       matlab.ui.control.RadioButton
        NoMemory                     matlab.ui.control.RadioButton
        ModalityButtonGroup          matlab.ui.container.ButtonGroup
        Behavior                     matlab.ui.control.RadioButton
        EEG                          matlab.ui.control.RadioButton
        fMRI                         matlab.ui.control.RadioButton
        HandednessButtonGroup        matlab.ui.container.ButtonGroup
        R                            matlab.ui.control.RadioButton
        L                            matlab.ui.control.RadioButton
        KeyModeIOIOButtonGroup       matlab.ui.container.ButtonGroup
        key_mode_1                   matlab.ui.control.RadioButton
        key_mode_2                   matlab.ui.control.RadioButton
    end


    properties (Access = public)
        % Initialize user input structure to pass to launch task function
        ui = struct;
        
    end

    properties (Access = private)
        % Defaults
        subject_ID = 'DH_test';
        task = 'IOIO';
        session = 'practice';
        modality = 'Behavior';
        run = 1;
        wm_task = 'Memory';
        handedness = 'r';
        key_mode = 1;
    end

    methods (Access = public)
    
        function results = func(app)
            results = app.ui;
        end
              
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: EEGrecordingstartedCheckBox
        function EEGrecordingstartedCheckBoxValueChanged(app, event)
            
        end

        % Selection changed function: HandednessButtonGroup
        function HandednessButtonGroupSelectionChanged(app, event)
            handedness_sel = app.HandednessButtonGroup.SelectedObject;
            
            switch handedness_sel
                case app.R
                    app.handedness = 'r';
                case app.L
                    app.handedness = 'l';
            end
            
        end

        % Selection changed function: ModalityButtonGroup
        function ModalityButtonGroupSelectionChanged(app, event)
            modality_sel = app.ModalityButtonGroup.SelectedObject;
            
            switch modality_sel
                case app.Behavior
                    app.modality = 'Behavior';
                case app.EEG
                    app.modality = 'EEG';
                case app.fMRI
                    app.modality = 'fMRI';               
            end
        end

        % Selection changed function: RunButtonGroup
        function RunButtonGroupSelectionChanged(app, event)
            run_sel = app.RunButtonGroup.SelectedObject;
            switch run_sel
                case app.run_1
                    app.run = 1;
                case app.run_2
                    app.run = 2;
            end
        end

        % Selection changed function: SessionButtonGroup
        function SessionButtonGroupSelectionChanged(app, event)
            session_sel = app.SessionButtonGroup.SelectedObject;
            
            switch session_sel
                case app.Practice
                    app.session = 'practice';
                case app.Experiment
                    app.session = 'experiment';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
    
            if (app.EEGrecordingstartedCheckBox.Value) == 0 &&...
                    strcmp(app.modality,'EEG')
                
                app.Label.Text = {'Please, start EEG';
                    'recording...'};
                
            else 
            
            % Create user input structure
                app.ui.subject_ID = app.subject_ID;
                app.ui.task = app.task;
                app.ui.session = app.session;
                app.ui.modality = app.modality;
                app.ui.run = app.run;
                app.ui.wm_task = app.wm_task;
                app.ui.handedness = app.handedness;
                app.ui.key_mode = app.key_mode;
                
                app.Label.Text = {'Experiment will begin...'};
                DH_COMPI_launch_task(app.ui);
                delete(app);
            end


        end

        % Callback function
        function StartExperimentButtonPushed(app, event)
            
        end

        % Value changed function: SubjectcodeEditField
        function SubjectcodeEditFieldValueChanged(app, event)
            app.subject_ID = app.SubjectcodeEditField.Value;
        end

        % Value changing function: SubjectcodeEditField
        function SubjectcodeEditFieldValueChanging(app, event)
            
        end

        % Selection changed function: TaskButtonGroup
        function TaskButtonGroupSelectionChanged(app, event)
            task_sel = app.TaskButtonGroup.SelectedObject;
            switch task_sel
                case app.IOIO
                    app.task = 'IOIO';
                case app.WM
                    app.task = 'WM';
                case app.MMN
                    app.task = 'MMN';
                case app.Resting
                    app.task = 'resting';
            end
                
        end

        % Selection changed function: WMButtonGroup
        function WMButtonGroupSelectionChanged(app, event)
            WM_task_sel = app.WMButtonGroup.SelectedObject;
            switch WM_task_sel
                case app.Memory
                    app.wm_task = 'Memory';
                case app.NoMemory
                    app.wm_task = 'NoMemory';
            end
        end

        % Size changed function: WMButtonGroup
        function WMButtonGroupSizeChanged(app, event)
           
        end

        % Callback function
        function WMTaskButtonGroupSelectionChanged(app, event)
            
        end

        % Selection changed function: KeyModeIOIOButtonGroup
        function KeyModeIOIOButtonGroupSelectionChanged(app, event)
            key_mode_sel = app.KeyModeIOIOButtonGroup.SelectedObject;
            switch key_mode_sel
                case app.key_mode_1
                    app.key_mode = 1;
                case app.key_mode_2
                    app.key_mode = 2;
            end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.COMPILauncherPanel = uipanel(app.UIFigure);
            app.COMPILauncherPanel.Title = 'COMPI Launcher';
            app.SubjectcodeEditFieldLabel = uilabel(app.COMPILauncherPanel);
            app.SubjectcodeEditFieldLabel.Text = 'Subject code';
            app.SubjectcodeEditField = uieditfield(app.COMPILauncherPanel, 'text');
            app.SubjectcodeEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectcodeEditFieldValueChanged, true);
            app.SubjectcodeEditField.ValueChangingFcn = createCallbackFcn(app, @SubjectcodeEditFieldValueChanging, true);
            app.TaskButtonGroup = uibuttongroup(app.COMPILauncherPanel);
            app.TaskButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TaskButtonGroupSelectionChanged, true);
            app.TaskButtonGroup.Title = 'Task';
            app.IOIO = uiradiobutton(app.TaskButtonGroup);
            app.IOIO.Text = 'IOIO';
            app.IOIO.Value = true;
            app.WM = uiradiobutton(app.TaskButtonGroup);
            app.WM.Text = 'WM';
            app.MMN = uiradiobutton(app.TaskButtonGroup);
            app.MMN.Text = 'MMN';
            app.Resting = uiradiobutton(app.TaskButtonGroup);
            app.Resting.Text = 'Resting';
            app.SessionButtonGroup = uibuttongroup(app.COMPILauncherPanel);
            app.SessionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SessionButtonGroupSelectionChanged, true);
            app.SessionButtonGroup.Title = 'Session';
            app.Practice = uiradiobutton(app.SessionButtonGroup);
            app.Practice.Text = 'Practice';
            app.Practice.Value = true;
            app.Experiment = uiradiobutton(app.SessionButtonGroup);
            app.Experiment.Text = 'Experiment';
            app.RunButtonGroup = uibuttongroup(app.COMPILauncherPanel);
            app.RunButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RunButtonGroupSelectionChanged, true);
            app.RunButtonGroup.Title = 'Run';
            app.run_1 = uiradiobutton(app.RunButtonGroup);
            app.run_1.Text = '1';
            app.run_1.Value = true;
            app.run_2 = uiradiobutton(app.RunButtonGroup);
            app.run_2.Text = '2';
            app.EEGrecordingstartedCheckBox = uicheckbox(app.COMPILauncherPanel);
            app.EEGrecordingstartedCheckBox.ValueChangedFcn = createCallbackFcn(app, @EEGrecordingstartedCheckBoxValueChanged, true);
            app.EEGrecordingstartedCheckBox.Text = 'EEG recording started';
            app.StartButton = uibutton(app.COMPILauncherPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Label = uilabel(app.COMPILauncherPanel);
            app.Label.Text = '';
            app.WMButtonGroup = uibuttongroup(app.COMPILauncherPanel);
            app.WMButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WMButtonGroupSelectionChanged, true);
            app.WMButtonGroup.Title = 'WM';
            app.WMButtonGroup.SizeChangedFcn = createCallbackFcn(app, @WMButtonGroupSizeChanged, true);
            app.Memory = uiradiobutton(app.WMButtonGroup);
            app.Memory.Text = 'Memory';
            app.Memory.Value = true;
            app.NoMemory = uiradiobutton(app.WMButtonGroup);
            app.NoMemory.Text = 'NoMemory';
            app.ModalityButtonGroup = uibuttongroup(app.COMPILauncherPanel);
            app.ModalityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModalityButtonGroupSelectionChanged, true);
            app.ModalityButtonGroup.Title = 'Modality';
            app.Behavior = uiradiobutton(app.ModalityButtonGroup);
            app.Behavior.Text = 'Behavior';
            app.Behavior.Value = true;
            app.EEG = uiradiobutton(app.ModalityButtonGroup);
            app.EEG.Text = 'EEG';
            app.fMRI = uiradiobutton(app.ModalityButtonGroup);
            app.fMRI.Text = 'fMRI';
            app.HandednessButtonGroup = uibuttongroup(app.COMPILauncherPanel);
            app.HandednessButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @HandednessButtonGroupSelectionChanged, true);
            app.HandednessButtonGroup.Title = 'Handedness';
            app.R = uiradiobutton(app.HandednessButtonGroup);
            app.R.Text = 'R';
            app.R.Value = true;
            app.L = uiradiobutton(app.HandednessButtonGroup);
            app.L.Text = 'L';
            app.KeyModeIOIOButtonGroup = uibuttongroup(app.COMPILauncherPanel);
            app.KeyModeIOIOButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @KeyModeIOIOButtonGroupSelectionChanged, true);
            app.KeyModeIOIOButtonGroup.Title = 'KeyMode (IOIO)';
            app.key_mode_1 = uiradiobutton(app.KeyModeIOIOButtonGroup);
            app.key_mode_1.Text = '1';
            app.key_mode_1.Value = true;
            app.key_mode_2 = uiradiobutton(app.KeyModeIOIOButtonGroup);
            app.key_mode_2.Text = '2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DH_COMPI_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end